classdef CalciumProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LoadImagesROITab             matlab.ui.container.Tab
        TOMCheckBox                  matlab.ui.control.CheckBox
        AddCellsButton               matlab.ui.control.Button
        right                        matlab.ui.control.Button
        left                         matlab.ui.control.Button
        LoadFileButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ImageSlider                  matlab.ui.control.Slider
        ImageLabel                   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        PopupandHalflifeTab          matlab.ui.container.Tab
        PlateauRangeAfterPeaksEditField  matlab.ui.control.NumericEditField
        PlateauRangeAfterPeaksEditFieldLabel  matlab.ui.control.Label
        FirstCutIndexEditField       matlab.ui.control.NumericEditField
        FirstCutIndexEditFieldLabel  matlab.ui.control.Label
        NextCutIndexEditField        matlab.ui.control.NumericEditField
        NextCutIndexEditFieldLabel   matlab.ui.control.Label
        NumberofROIsEditField        matlab.ui.control.NumericEditField
        NumberofROIsEditFieldLabel   matlab.ui.control.Label
        CutIndexEditField            matlab.ui.control.NumericEditField
        CutIndexEditFieldLabel       matlab.ui.control.Label
        FolderPathEditField          matlab.ui.control.EditField
        FolderPathEditFieldLabel     matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        ResultsNormalizationwithTimeStampplusHalfLifeAnalysisLabel  matlab.ui.control.Label
        NottheFirstCutCheckBox       matlab.ui.control.CheckBox
        EnterButton                  matlab.ui.control.Button
        NottheLastCutCheckBox        matlab.ui.control.CheckBox
        InputCutsButton              matlab.ui.control.Button
        ExtractPeakParametersTab     matlab.ui.container.Tab
        RunButton                    matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        AllCheckBox                  matlab.ui.control.CheckBox
        NeuronsEditField             matlab.ui.control.EditField
        NeuronsEditFieldLabel        matlab.ui.control.Label
        ProminenceEditField          matlab.ui.control.EditField
        ProminenceEditFieldLabel     matlab.ui.control.Label
        MinHeightEditField           matlab.ui.control.EditField
        ListBox                      matlab.ui.control.ListBox
        ChooseDirectoryButton        matlab.ui.control.Button
        MinHeightEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
        roi
        means
        numImages
        cuts
        
        caDirectory
        caFiles
    end
    
    methods (Access = private)
        
        function displayImage(app, index)
            set(app.ImageSlider, 'value', index)
            
            file = app.tifFiles(round(index)); % why is there a round y?
            filePath = fullfile(file.folder, file.name);
            % baseFileName = file.name; 
            % fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ... % dunno what this does, oh well
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
            app.roi = {};
            app.cuts = {};
            
            app.numImages = 0;
        end

        % Button pushed function: LoadFileButton
        function LoadFIle(app, event)
            try
                app.myFolder = uigetdir('C:');  % get file
                figure(app.UIFigure); % focus window again
                app.filePattern = fullfile(app.myFolder + "\GFP", '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                
                app.numImages = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1 app.numImages];
                
                if not(app.numImages)
                    return;
                end
            
                set(app.ImageSlider, 'value', 1) % sets slider
                displayImage(app, 1);
            catch
                warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Button pushed function: left
        function leftPressed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = max(app.ImageSlider.Value-1, 1);
            displayImage(app, y);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y);
        end

        % Callback function
        function PlayButtonPushed(app, event)
            app.play = not(app.play);
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            if not(app.numImages)
                return;
            end
            
            y = event.Value;
            displayImage(app, y);
        end

        % Button pushed function: AddCellsButton
        function AddCellsButtonPushed(app, event)
            answer = str2double(inputdlg("How many cells are in the Image?")); % takes number
            colors = ["r", "m", "y", "g", "b", "c", "w", "b"];
            for i = 1:answer                
                app.Label.Text = "Draw Cell " + i;
                app.roi{i} = drawpolygon(app.UIAxes, "FaceAlpha", 0); %draws polygon
                app.roi{i}.Color = colors{i};
                app.roi{i}.Label = sprintf("Cell %d",i);
            end
            app.Label.Text = "Draw backgorund";
            app.roi{answer+1} = drawpolygon(app.UIAxes, "FaceAlpha", 0); %draws polygon
            
            x = app.ImageSlider.Value;
            baseFileName = app.tifFiles(round(x)).name;
            app.Label.Text = baseFileName;
            
            app.means = zeros(app.numImages, answer+1);
            
            for i = 1:app.numImages
                baseFileName = app.tifFiles(i).name;
                fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
                imageArray = imread(fullFileName); %gets the image since regionprops doesn't work with UIAxes
                for j = 1:answer+1
                    mask = createMask(app.roi{j});
                    avgintensity = struct2array(regionprops(mask, imageArray,"MeanIntensity")); % gets the mean intensity for the mask within the image
                    % disp(avgintensity);
                    app.means(i, j) = avgintensity(1);
                    
                end
            end
            disp(app.means)
            writematrix(app.means, 'Results.csv');
            
            if app.TOMCheckBox == 1
                app.filePattern = fullfile(app.myFolder + "\TOM", '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                for i = 1:app.numImages
                    baseFileName = app.tifFiles(i).name;
                    fullFileName = fullfile(app.myFolder + "\TOM", baseFileName);
                    imageArray = imread(fullFileName); %gets the image since regionprops doesn't work with UIAxes
                    for j = 1:answer+1
                        mask = createMask(app.roi{j});
                        avgintensity = struct2array(regionprops(mask, imageArray,"MeanIntensity")); % gets the mean intensity for the mask within the image
                        % disp(avgintensity);
                        app.means(i, j) = avgintensity(1);
                        
                    end
                end
                disp(app.means)
                writematrix(app.means, 'ResultsTOM.csv');
            end
            
            
        end

        % Callback function
        function TrimButtonPushed(app, event)
            outputFolder = uigetdir('C:'); % takes in a directory for output
            answer = inputdlg("number of files in output"); % takes number
            trimfiles(app.myFolder + "\GFP", outputFolder, str2double(answer)); % calls function
            
            app.myFolder = outputFolder;
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            app.play = false;
            
            len = length(app.tifFiles);
            app.ImageSlider.Limits = [0 len-1];
            
            set(app.ImageSlider, 'value', 1)
            baseFileName = app.tifFiles(1).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Button pushed function: InputCutsButton
        function InputCutsButtonPushed(app, event)
            answer = str2double(inputdlg("How many cuts are there"));
            for i = 1:answer
                app.cuts{i} = str2double(inputdlg("Which image is the cut"));
            end
        end

        % Value changed function: NottheLastCutCheckBox
        function NottheLastCutCheckBoxValueChanged(app, event)
            value = app.NottheLastCutCheckBox.Value;
            
            switch value
                case 0
                    app.NextCutIndexEditField.Visible = 'off';
                    app.NextCutIndexEditFieldLabel.Visible = 'off';
                case 1
                    app.NextCutIndexEditField.Visible = 'on';
                    app.NextCutIndexEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
            function fileList = getFiles(dirName)
            dirData = dir(dirName);      %# Get the data for the current directory
            dirIndex = [dirData.isdir];  %# Find the index for directories
            fileList = {dirData(~dirIndex).name}';  %'# Get a list of the files
            end
            done = "done";
                
            notfirstcutval = app.NottheFirstCutCheckBox.Value;
            tomval = app.TOMCheckBox.Value;
            if notfirstcutval == 1
                firstcut_index = app.FirstCutIndexEditField.Value;
            else
                
                firstcut_index = app.CutIndexEditField.Value;
                
                gfpfilepath = app.FolderPathEditField.Value + "\data\Results.csv";
                tomfilepath = app.FolderPathEditField.Value + "\data\ResultsTOM.csv";
                
                data = csvread(gfpfilepath,1,2);
                if tomval == 1
                tomdata = csvread(tomfilepath, 1,2);
                end

                columntot = app.NumberofROIsEditField.Value + 1;
                operatecolumn = app.NumberofROIsEditField.Value;
                photopath = app.FolderPathEditField.Value +"\GFP";
                writeto = app.FolderPathEditField.Value + "\data\ROI";
            
                fileNames = getFiles(photopath);
            
                time = extractBetween(fileNames,"_20","_ms");%extracted time strings from all files
            %convert everything to seconds
                hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
                minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
                second = str2num((char((extractBetween(time, "m_","s")))));
                totseconds = hour_sec + minute_sec + second;
            
            %plot(totseconds) to check
            
            %subtract seconds from first
                subtractsecond = totseconds - totseconds(1);

                if tomval == 1
                    GFPoperatedata = data(:,1:operatecolumn);
                    GFPMean = GFPoperatedata - data(:, columntot);
                    TOMoperatedata = tomdata(:,1:operatecolumn);
                    TOMMean = TOMoperatedata - tomdata(:, columntot);
                    Mean = GFPMean ./ TOMMean;
                    row = Mean(firstcut_index,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_TOM_Divide ',num2str(firstcut_index), meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
                    done
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
                else % dividing and normalizing
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata - data(:, columntot);
                    row = Mean(firstcut_index,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_Divide ',num2str(firstcut_index), meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
                    done
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
                end
            end
        %Half-Life Analysis Starts
            root1 = app.FolderPathEditField.Value + "\data\";
            root2 = "ROI\";
            root = root1 + root2;
            filetype = '.csv';
            final = zeros(1,2); %creates an empty matrix to be filled later with values
            cutindex = app.CutIndexEditField.Value;
            nextcutval = app.NottheLastCutCheckBox.Value;
            %tomval = app.TOMCheckBox.Value;

            
            for i = 1:app.NumberofROIsEditField.Value
                if tomval == 1
                    filenum =  "Normalize_TOM_Divide" + num2str(firstcut_index) + "Mean" + num2str(i);
                else
                    filenum =  "Normalize_Divide" + num2str(firstcut_index) + "Mean" + num2str(i);
                end
               
                filepath = strcat(root, filenum, filetype); %this creates/puts together the filename
                wholematrix = csvread(filepath, 1, 0); %reads the files
                x = wholematrix(:,1); %creates an array of x values (times)
                y = wholematrix(:,2); %creates an array of y values (intensities)
                
                if nextcutval == 1
                next_cutindex = app.NextCutIndexEditField.Value;
                else
                next_cutindex = numel(x);
                end
                cuttime = x(cutindex);
                next_cuttime = x(next_cutindex);
                cy = y(cutindex:end); 
                cx = x(cutindex:end);

    %FIND PEAK
    %----------------------------------------
                last_image_time = cx(end,:);
                peakrange_time = cuttime + 45;
                if peakrange_time > last_image_time
                    peakrange_time = last_image_time;
                end
                after_peakrange = find(cx>=peakrange_time); %this takes all the indexes of all the intensities in findrange that are less than or equal to the peak value
                peakrange_onsheet = cx(after_peakrange(1)); %after_peakrange(1) is essentially the index of the first value after the peakrange
                peakrange_index = find(x==peakrange_onsheet,1, 'first'); %this takes the actual index of the peakrange
    
                last_image_index = find(x==last_image_time);
                cy_peak_range = y(cutindex:peakrange_index);%creates a new intensity array that only contains possible range of peak.

                peak = max(cy_peak_range); %finds the value of the intensity of the peak
                peakindex = find(cy==peak,1,'first'); %indexes the value so we can locate the corresponding time in "cx" for this peak
    %----------------------------------------
    
    %FIND PLATEAU
    %----------------------------------------
                peaktime = cx(peakindex); %locates the corresponding time to the peak and saves that value
    %NEED A IF STATEMENT HERE
    
                plateaurange_time = peaktime + app.PlateauRangeAfterPeaksEditField.Value;
                if plateaurange_time > next_cuttime
                    plateaurange_onsheet = next_cuttime;
                else
                    after_plateaurange = find(cx>=plateaurange_time); %this takes all the indexes of all the intensities in findrange that are less than or equal to the plateau value
                    plateaurange_onsheet = cx(after_plateaurange(1)); %after_plateaurange(1) is essentially the index of the first value after the plateaurange
                end
                plateaurange_index = find(cx==plateaurange_onsheet); %,1, 'first'this takes the actual index of the plateaurange
                plateau = min(cy(peakindex:plateaurange_index)); %find the mininum between the peak and the following cut as the plateau
    
                plateau_index = find(cy==plateau,1,'first');%find the index location of the plateau
    %mihgt have error here if there is no peak.
                if plateau_index < peakindex %In case there is a image before peak which has extact same intensity as plateau
                    plateau_index = find(cy==plateau,1,'last');%find the index location of the plateau
                end 
    %----------------------------------------

    %FITTING
    %----------------------------------------
    intensity_array = cy(peakindex:plateau_index); %create an intensity array from the peak to the plateau
    time_array = cx(peakindex:plateau_index); %create an time array from the peak to the plateau
    
    plateau_time = cx(plateau_index);
    
    points_num = numel(intensity_array);%number of intensity array elements
    try
        opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
        opts.Display = 'Off';
        %opts.Normalize = 'on';
        
        [f, gof] = fit(intensity_array,time_array,'exp2',opts); %2 phase exponential decay fitting
   
        half_peak = (peak - plateau)/2 + plateau;
        half_life = f(half_peak) - peaktime;

        r2 = gof.rsquare; %Obtain R-square to examine the goodness of fitting.
    catch %Any error goes to NA
        half_life = "NA";
        r2 = "NA";
    end
    %----------------------------------------
    final(i,1) = i; %this serves as a label to identify the time with the csv file
    final(i,2) = peaktime;
    final(i,3) = peak;
    final(i,4) = plateau_time; %saves the subtracted time between the peaks to a location in the matrix
    final(i,5) = plateau;
    final(i,6) = half_life;
    final(i,7) = r2;
    final(i,8) = points_num;
            end
            output = array2table(final,'VariableNames',{'Cell','Peak_Time','Peak_Value','Plateau_Time','Plateau_Value','Half_Life','Rsquare','Num_Points'}); %writes the matrix containing all subtracted times to a single csv file containing all the times

            output_filename = "Half-life for the "+cuttime+"s cut";

            finalwrite = strcat(root1,output_filename,filetype); %will write to the location where all the csv files are

            writetable(output,finalwrite);
        end

        % Value changed function: NottheFirstCutCheckBox
        function NottheFirstCutCheckBoxValueChanged(app, event)
            value = app.NottheFirstCutCheckBox.Value;
            switch value
                case 0
                    app.FirstCutIndexEditField.Visible = 'off';
                    app.FirstCutIndexEditFieldLabel.Visible = 'off';
                case 1
                    app.FirstCutIndexEditField.Visible = 'on';
                    app.FirstCutIndexEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: ChooseDirectoryButton
        function ChooseDirectoryButtonPushed(app, event)
            app.caDirectory = uigetdir;
            figure(app.UIFigure); % focus window again
            
            app.caFiles = dir(fullfile(app.caDirectory, "*.csv"));            
            app.ChooseDirectoryButton.Text = app.caDirectory;
            
            app.ListBox.Items = arrayfun(@(x) x.name, app.caFiles, 'uniformoutput', false);
            
            if app.AllCheckBox.Value
                if ~isempty(app.ListBox.Items) % TODO - copied code, maybe make helper?
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            directory = app.caDirectory; % Making code more readable + copy/paste
            
            
            if isempty(app.NeuronsEditField.Value)
                if ~isempty(app.ListBox.Items) % TODO - copied code, maybe make helper?
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
            end
            
            neruons = str2num(app.NeuronsEditField.Value);
            neruons = neruons(0 < neruons & neruons < length(app.ListBox.Items) + 1);
            
            Analyze_Calcium_Activity(directory,files,method,neurons,append,stim_pos,user_data,write);
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            value = app.AllCheckBox.Value;
            
            if value
                if ~isempty(app.ListBox.Items)
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
                
                app.NeuronsEditField.Editable = false;
            else
                app.NeuronsEditField.Editable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImagesROITab = uitab(app.TabGroup);
            app.LoadImagesROITab.Title = 'Load Images + ROI';
            app.UIAxes = uiaxes(app.LoadImagesROITab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageLabel = uilabel(app.LoadImagesROITab);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.LoadImagesROITab);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.Value = 1;
            app.Label = uilabel(app.LoadImagesROITab);
            app.Label.Text = '';
            app.LoadFileButton = uibutton(app.LoadImagesROITab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFileButton.Text = {'Load File'; ''};
            app.left = uibutton(app.LoadImagesROITab, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<';
            app.right = uibutton(app.LoadImagesROITab, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '->';
            app.AddCellsButton = uibutton(app.LoadImagesROITab, 'push');
            app.AddCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellsButtonPushed, true);
            app.AddCellsButton.Text = 'Add Cells';
            app.TOMCheckBox = uicheckbox(app.LoadImagesROITab);
            app.TOMCheckBox.Text = 'TOM';
            app.PopupandHalflifeTab = uitab(app.TabGroup);
            app.PopupandHalflifeTab.Title = 'Popup and Halflife';
            app.InputCutsButton = uibutton(app.PopupandHalflifeTab, 'push');
            app.InputCutsButton.ButtonPushedFcn = createCallbackFcn(app, @InputCutsButtonPushed, true);
            app.InputCutsButton.Text = {'Input Cuts'; ''};
            app.NottheLastCutCheckBox = uicheckbox(app.PopupandHalflifeTab);
            app.NottheLastCutCheckBox.ValueChangedFcn = createCallbackFcn(app, @NottheLastCutCheckBoxValueChanged, true);
            app.NottheLastCutCheckBox.Text = 'Not the Last Cut';
            app.EnterButton = uibutton(app.PopupandHalflifeTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NottheFirstCutCheckBox = uicheckbox(app.PopupandHalflifeTab);
            app.NottheFirstCutCheckBox.ValueChangedFcn = createCallbackFcn(app, @NottheFirstCutCheckBoxValueChanged, true);
            app.NottheFirstCutCheckBox.Text = 'Not the First Cut';
            app.ResultsNormalizationwithTimeStampplusHalfLifeAnalysisLabel = uilabel(app.PopupandHalflifeTab);
            app.ResultsNormalizationwithTimeStampplusHalfLifeAnalysisLabel.Text = 'Results Normalization with Time Stamp plus Half-Life Analysis';
            app.Label_2 = uilabel(app.PopupandHalflifeTab);
            app.Label_2.Text = {'1. Folder Path is the upper folder path of the data/GFP/TOM folders.'; '2. Data folder has to be named as "data", as well as ROI folder'; '3. GFP results file has to be named as "Results.csv".'; '4. TOM results file has to be named as "ResultsTOM.csv".'};
            app.FolderPathEditFieldLabel = uilabel(app.PopupandHalflifeTab);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.PopupandHalflifeTab, 'text');
            app.CutIndexEditFieldLabel = uilabel(app.PopupandHalflifeTab);
            app.CutIndexEditFieldLabel.Text = 'Cut Index';
            app.CutIndexEditField = uieditfield(app.PopupandHalflifeTab, 'numeric');
            app.NumberofROIsEditFieldLabel = uilabel(app.PopupandHalflifeTab);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIsEditField = uieditfield(app.PopupandHalflifeTab, 'numeric');
            app.NextCutIndexEditFieldLabel = uilabel(app.PopupandHalflifeTab);
            app.NextCutIndexEditFieldLabel.Visible = 'off';
            app.NextCutIndexEditFieldLabel.Text = 'Next Cut Index';
            app.NextCutIndexEditField = uieditfield(app.PopupandHalflifeTab, 'numeric');
            app.NextCutIndexEditField.Visible = 'off';
            app.FirstCutIndexEditFieldLabel = uilabel(app.PopupandHalflifeTab);
            app.FirstCutIndexEditFieldLabel.Visible = 'off';
            app.FirstCutIndexEditFieldLabel.Text = 'First Cut Index';
            app.FirstCutIndexEditField = uieditfield(app.PopupandHalflifeTab, 'numeric');
            app.FirstCutIndexEditField.Visible = 'off';
            app.PlateauRangeAfterPeaksEditFieldLabel = uilabel(app.PopupandHalflifeTab);
            app.PlateauRangeAfterPeaksEditFieldLabel.Text = 'Plateau Range After Peak (s)';
            app.PlateauRangeAfterPeaksEditField = uieditfield(app.PopupandHalflifeTab, 'numeric');
            app.ExtractPeakParametersTab = uitab(app.TabGroup);
            app.ExtractPeakParametersTab.Title = 'Extract Peak Parameters';
            app.MinHeightEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.MinHeightEditFieldLabel.Text = 'Min Height';
            app.ChooseDirectoryButton = uibutton(app.ExtractPeakParametersTab, 'push');
            app.ChooseDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDirectoryButtonPushed, true);
            app.ChooseDirectoryButton.Text = 'Choose Directory';
            app.ListBox = uilistbox(app.ExtractPeakParametersTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.MinHeightEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.MinHeightEditField.Value = '0.1';
            app.ProminenceEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.ProminenceEditFieldLabel.Text = 'Prominence';
            app.ProminenceEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.ProminenceEditField.Value = '0.1';
            app.NeuronsEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.NeuronsEditFieldLabel.Text = 'Neurons';
            app.NeuronsEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.AllCheckBox = uicheckbox(app.ExtractPeakParametersTab);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.DropDown = uidropdown(app.ExtractPeakParametersTab);
            app.DropDown.Items = {'Display', 'Write to Output', 'Append to Output'};
            app.DropDown.Value = 'Display';
            app.RunButton = uibutton(app.ExtractPeakParametersTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end