classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        right           matlab.ui.control.Button
        left            matlab.ui.control.Button
        Label           matlab.ui.control.Label
        LoadFIleButton  matlab.ui.control.Button
        ImageSlider     matlab.ui.control.Slider
        ImageLabel      matlab.ui.control.Label
        PlayButton      matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myFolder = 'C:\Users\maxw0\Matlab Code\tether images\cut cell 19 good';
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            app.play = false;
            %while true
            if app.play
                x = app.ImageSlider.Value;
                value = x+1;
                set(app.ImageSlider, 'value', y)
                app.Label.Text = int2str(y)
                baseFileName = app.tifFiles(value).name;
                fullFileName = fullfile(app.myFolder, baseFileName);
                %fprintf(1, 'Now reading %s\n', fullFileName);
                imageArray = imread(fullFileName);
                imshow(imageArray);  % Display image.
                drawnow; % Force display to update immediately.
            end
            %end
        end

        % Button pushed function: LoadFIleButton
        function LoadFIle(app, event)
            len = length(app.tifFiles)
            app.ImageSlider.Limits = [0 len];
            %k = 1
            %baseFileName = tifFiles(k).name;
            %fullFileName = fullfile(myFolder, baseFileName);
            %fprintf(1, 'Now reading %s\n', fullFileName);
            %imageArray = imread(fullFileName);
            %imshow(imageArray);  % Display image.
            %drawnow; % Force display to update immediately.
        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)
            x = app.ImageSlider.Value;
            vm = 3;
            y = 0:vm:(x+vm);
            [~,idx] = min(abs(x-y));
            value = y(idx);
            set(app.ImageSlider, 'Value', value-1);
            app.Label.Text = int2str(value);
            
            baseFileName = app.tifFiles(value).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            %fprintf(1, 'Now reading %s\n', fullFileName);
            imageArray = imread(fullFileName);
            %app.Image.ImageSource = fullFileName;
            imshow(imageArray, 'parent', app.UIAxes);
            %imshow(imageArray);  % Display image.
            drawnow; % Force display to update immediately.
        end

        % Button pushed function: left
        function leftPressed(app, event)
            x = app.ImageSlider.Value;
            y = x-1;
            set(app.ImageSlider, 'value', y)
            app.Label.Text = int2str(y)
            
            baseFileName = app.tifFiles(round(y)).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            %fprintf(1, 'Now reading %s\n', fullFileName);
            imageArray = imread(fullFileName);
            imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            drawnow;
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            x = app.ImageSlider.Value;
            y = x+1;
            set(app.ImageSlider, 'value', y)
            app.Label.Text = int2str(y)
            
            baseFileName = app.tifFiles(round(y)).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            %fprintf(1, 'Now reading %s\n', fullFileName);
            imageArray = imread(fullFileName);
            imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            drawnow;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play = not(app.play)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.UIFigure);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.LoadFIleButton = uibutton(app.UIFigure, 'push');
            app.LoadFIleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFIleButton.Text = {'Load FIle'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.left = uibutton(app.UIFigure, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<-';
            app.right = uibutton(app.UIFigure, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '->';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end